classdef comp1 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        Panel             matlab.ui.container.Panel
        EditField_6       matlab.ui.control.EditField
        EditField_6Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        EditField_3Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button3           matlab.ui.control.Button
        Button2           matlab.ui.control.Button
        Button1           matlab.ui.control.Button
        Button7           matlab.ui.control.Button
        Button6           matlab.ui.control.Button
        Button5           matlab.ui.control.Button
        Button4           matlab.ui.control.Button
        Tab2              matlab.ui.container.Tab
    end


    methods (Access = public)
        
        function results = func(~,b,c,d,e)
            disp(b);
            disp(c);
            disp(d);
            disp(e);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1_REQUEST(comp, event)
            a = comp.EditField.Value;
            b = string(comp.EditField_3.Value);
            img = imread(string(a));
            img=grey_image_generate(img);
            figure;
            imshow(img);      
            title('灰度图像');
            switch (b)
                case 'line'
                    t = Lineargrayscaletransformation(img,double(string(comp.EditField_4.Value)));
                    figure;
                    imshow(t);      
                    title('线性变换图像');
                case 'index'
                    t = indextransformation(img,double(string(comp.EditField_4.Value)),double(string(comp.EditField_6.Value)));
                    figure;
                    imshow(t);      
                    title('指数变换图像');
                case 'log'
                    t = logtransformation(img,double(string(comp.EditField_4.Value)),double(string(comp.EditField_6.Value)));
                    figure;
                    imshow(t);      
                    title('对数变换图像');
                otherwise
                    error('Unsupported method. Use "roberts", "prewitt", "sobel", or "laplacian".');
                    return;
            end
            
            
        end

        % Button pushed function: Button2
        function Button2_REQUEST(comp, event)
            a = comp.EditField.Value;
            img = imread(string(a));
            img=grey_image_generate(img);
            figure;
            imshow(img);      
            title('灰度图像');
            b = string(comp.EditField_3.Value);
            switch (b)
                case 'show'
                    t = grey_histeq(img);
                    figure;
                    bar(histogram_generate(img));      
                    title('直方图均衡化前');
                    figure;
                    imshow(t);      
                    title('直方图均衡化结果');
                    figure;
                    bar(histogram_generate(t));      
                    title('直方图均衡化后');
                    mix(:,:,1)=t;
                    mix(:,:,2)=t;
                    mix(:,:,3)=t;
                    imwrite(uint8(mix),"2.jpg");
                case 'equal'
                    img2 = imread(string(comp.EditField_2.Value));
                    img2=grey_image_generate(img2);
                    figure;
                    imshow(img2);      
                    title('对比图像');
                    img=grey_histeq_equip(img,img2);
                    figure;
                    imshow(img);
                    title('规定化图像');
                otherwise
                    error('Unsupported method. Use "roberts", "prewitt", "sobel", or "laplacian".');
                    return;
            end
        end

        % Button pushed function: Button3
        function Button3Pushed(comp, event)
            a = comp.EditField.Value;
            img = imread(string(a));
            img=grey_image_generate(img);
            figure;
            imshow(img);      
            title('灰度图像');
            b = string(comp.EditField_3.Value);
            switch (b)
                case 'expend'
                    c=grey_expend(img,double(string(comp.EditField_4.Value)));
                    figure;
                    imshow(c);
                    title('放大图像');
                case 'revolve'
                    c=grey_revolve(img,double(string(comp.EditField_4.Value)));
                    figure;
                    imshow(c);
                    title('旋转图像');
                otherwise
                    error('Unsupported method. Use "roberts", "prewitt", "sobel", or "laplacian".');
                    return;
            end
        end

        % Button pushed function: Button4
        function Button4Pushed(comp, event)
            a = comp.EditField.Value;
            img = imread(string(a));
            img=grey_image_generate(img);
            figure;
            imshow(img);
            title('灰度图像');
            i=regexp(string(comp.EditField_4.Value), ' ', 'split');
            img=addNoiseToImage(img,string(comp.EditField_3.Value),str2double(i));
            imshow(img);
            title('噪声图像');
            b=string(comp.EditField_6.Value);
            switch (b)
                case 'ideal_lowpass'
                    c=frequencyFilter(img,b,double(string(comp.EditField_5.Value)));
                    figure;
                    imshow(c);
                    title('频域滤波图像');
                case 'gaussian_lowpass'
                    c=frequencyFilter(img,b,double(string(comp.EditField_5.Value)));
                    figure;
                    imshow(c);
                    title('高斯频域滤波图像');
                case 'mean'
                    c=applySpatialFilter(img,b,double(string(comp.EditField_5.Value)));
                    figure;
                    imshow(c);
                    title('均值空域滤波图像');
                case 'gaussian'
                    c=applySpatialFilter(img,b,double(string(comp.EditField_5.Value)));
                    figure;
                    imshow(c);
                    title('高斯空域滤波图像');
                otherwise
                    error('Unsupported method. Use "roberts", "prewitt", "sobel", or "laplacian".');
                    return;
            end
        end

        % Button pushed function: Button5
        function Button5Pushed(comp, event)
            a = comp.EditField.Value;
            img = imread(string(a));
            img=grey_image_generate(img);
            figure;
            imshow(img);
            title('灰度图像');
            b = string(comp.EditField_3.Value);
            img2=edge_detection(img, b);
            figure;
            imshow(img2);
            title('边缘检测图像');
        end

        % Button pushed function: Button6
        function Button6Pushed(comp, event)
            a = comp.EditField.Value;  
            img = imread(string(a)); 
            A=grey_image_generate(img);
            calculateLBP(A,1,8,1);
            calculateHOG(img);
        end

        % Button pushed function: Button7
        function Button7Pushed(comp, event)
            targetExtractthetarget(string(comp.EditField.Value),string(comp.EditField_2.Value));
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组建
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 500 500];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create TabGroup
            comp.TabGroup = uitabgroup(comp);
            comp.TabGroup.Position = [1 1 500 500];

            % Create Tab
            comp.Tab = uitab(comp.TabGroup);
            comp.Tab.Title = 'Tab';

            % Create Panel
            comp.Panel = uipanel(comp.Tab);
            comp.Panel.Title = 'Panel';
            comp.Panel.Position = [1 0 500 473];

            % Create ButtonGroup
            comp.ButtonGroup = uibuttongroup(comp.Panel);
            comp.ButtonGroup.Title = 'Button Group';
            comp.ButtonGroup.Position = [0 0 123 450];

            % Create Button4
            comp.Button4 = uibutton(comp.ButtonGroup, 'push');
            comp.Button4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button4Pushed, true);
            comp.Button4.Position = [11 291 100 23];
            comp.Button4.Text = 'Button4';

            % Create Button5
            comp.Button5 = uibutton(comp.ButtonGroup, 'push');
            comp.Button5.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button5Pushed, true);
            comp.Button5.Position = [12 253 100 23];
            comp.Button5.Text = 'Button5';

            % Create Button6
            comp.Button6 = uibutton(comp.ButtonGroup, 'push');
            comp.Button6.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button6Pushed, true);
            comp.Button6.Position = [11 215 100 23];
            comp.Button6.Text = 'Button6';

            % Create Button7
            comp.Button7 = uibutton(comp.ButtonGroup, 'push');
            comp.Button7.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button7Pushed, true);
            comp.Button7.Position = [11 170 100 23];
            comp.Button7.Text = 'Button7';

            % Create Button1
            comp.Button1 = uibutton(comp.ButtonGroup, 'push');
            comp.Button1.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button1_REQUEST, true);
            comp.Button1.Position = [12 402 100 23];
            comp.Button1.Text = 'Button1';

            % Create Button2
            comp.Button2 = uibutton(comp.ButtonGroup, 'push');
            comp.Button2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button2_REQUEST, true);
            comp.Button2.Position = [11 366 100 23];
            comp.Button2.Text = 'Button2';

            % Create Button3
            comp.Button3 = uibutton(comp.ButtonGroup, 'push');
            comp.Button3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button3Pushed, true);
            comp.Button3.Position = [11 333 100 23];
            comp.Button3.Text = 'Button3';

            % Create Label
            comp.Label = uilabel(comp.Panel);
            comp.Label.HorizontalAlignment = 'right';
            comp.Label.Position = [237 428 36 22];
            comp.Label.Text = '图像1';

            % Create EditField
            comp.EditField = uieditfield(comp.Panel, 'text');
            comp.EditField.Position = [288 428 100 22];

            % Create EditField_2Label
            comp.EditField_2Label = uilabel(comp.Panel);
            comp.EditField_2Label.HorizontalAlignment = 'right';
            comp.EditField_2Label.Position = [237 388 36 22];
            comp.EditField_2Label.Text = '图像2';

            % Create EditField_2
            comp.EditField_2 = uieditfield(comp.Panel, 'text');
            comp.EditField_2.Position = [288 390 100 22];

            % Create EditField_3Label
            comp.EditField_3Label = uilabel(comp.Panel);
            comp.EditField_3Label.HorizontalAlignment = 'right';
            comp.EditField_3Label.Position = [237 354 36 22];
            comp.EditField_3Label.Text = '参数1';

            % Create EditField_3
            comp.EditField_3 = uieditfield(comp.Panel, 'text');
            comp.EditField_3.Position = [288 354 100 22];

            % Create Label_2
            comp.Label_2 = uilabel(comp.Panel);
            comp.Label_2.HorizontalAlignment = 'right';
            comp.Label_2.Position = [237 308 36 22];
            comp.Label_2.Text = '参数2';

            % Create EditField_4
            comp.EditField_4 = uieditfield(comp.Panel, 'text');
            comp.EditField_4.Position = [288 308 100 22];

            % Create Label_3
            comp.Label_3 = uilabel(comp.Panel);
            comp.Label_3.HorizontalAlignment = 'right';
            comp.Label_3.Position = [237 214 36 22];
            comp.Label_3.Text = '参数4';

            % Create EditField_5
            comp.EditField_5 = uieditfield(comp.Panel, 'text');
            comp.EditField_5.Position = [288 214 100 22];

            % Create EditField_6Label
            comp.EditField_6Label = uilabel(comp.Panel);
            comp.EditField_6Label.HorizontalAlignment = 'right';
            comp.EditField_6Label.Position = [237 262 36 22];
            comp.EditField_6Label.Text = '参数3';

            % Create EditField_6
            comp.EditField_6 = uieditfield(comp.Panel, 'text');
            comp.EditField_6.Position = [288 262 100 22];

            % Create Tab2
            comp.Tab2 = uitab(comp.TabGroup);
            comp.Tab2.Title = 'Tab2';
        end
    end
end